classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        InputTab                      matlab.ui.container.Tab
        RefreshButton                 matlab.ui.control.Button
        Delta_thEditField             matlab.ui.control.NumericEditField
        Delta_thEditFieldLabel        matlab.ui.control.Label
        eEditField_2                  matlab.ui.control.NumericEditField
        eEditField_2Label             matlab.ui.control.Label
        OMEditField_2                 matlab.ui.control.NumericEditField
        OMEditField_2Label            matlab.ui.control.Label
        iEditField_2                  matlab.ui.control.NumericEditField
        iEditField_2Label             matlab.ui.control.Label
        omEditField_2                 matlab.ui.control.NumericEditField
        omEditField_2Label            matlab.ui.control.Label
        aKmEditField_2                matlab.ui.control.NumericEditField
        aKmEditField_2Label           matlab.ui.control.Label
        th_fEditField                 matlab.ui.control.NumericEditField
        th_fEditFieldLabel            matlab.ui.control.Label
        th_0EditField                 matlab.ui.control.NumericEditField
        th_0EditFieldLabel            matlab.ui.control.Label
        muEditField                   matlab.ui.control.NumericEditField
        muEditFieldLabel              matlab.ui.control.Label
        OtherconstantsTab             matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        raEditField                   matlab.ui.control.NumericEditField
        raEditFieldLabel              matlab.ui.control.Label
        rpEditField                   matlab.ui.control.NumericEditField
        rpEditFieldLabel              matlab.ui.control.Label
        bEditField                    matlab.ui.control.NumericEditField
        bEditFieldLabel               matlab.ui.control.Label
        hEditField_2                  matlab.ui.control.NumericEditField
        hLabel                        matlab.ui.control.Label
        pEditField                    matlab.ui.control.NumericEditField
        pEditFieldLabel               matlab.ui.control.Label
        EEditField                    matlab.ui.control.NumericEditField
        EEditFieldLabel               matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        Button_5                      matlab.ui.control.StateButton
        Button_4                      matlab.ui.control.StateButton
        Button_3                      matlab.ui.control.StateButton
        norbitsEditField              matlab.ui.control.NumericEditField
        norbitsEditFieldLabel         matlab.ui.control.Label
        AnimationButton               matlab.ui.control.Button
        StateButton                   matlab.ui.control.Button
        ConstansButton                matlab.ui.control.Button
        ConversionsButton             matlab.ui.control.Button
        ConversionsPanel              matlab.ui.container.Panel
        XEditField                    matlab.ui.control.NumericEditField
        XEditFieldLabel               matlab.ui.control.Label
        PasteintoConstantsCheckBox    matlab.ui.control.CheckBox
        RunButton                     matlab.ui.control.Button
        ButtonGroup                   matlab.ui.container.ButtonGroup
        KeptoCarButton                matlab.ui.control.RadioButton
        CartoKepButton                matlab.ui.control.RadioButton
        VzEditField                   matlab.ui.control.NumericEditField
        VzEditFieldLabel              matlab.ui.control.Label
        VyEditField                   matlab.ui.control.NumericEditField
        VyEditField_2Label            matlab.ui.control.Label
        VxEditField                   matlab.ui.control.NumericEditField
        VxEditFieldLabel              matlab.ui.control.Label
        ZEditField                    matlab.ui.control.NumericEditField
        ZEditFieldLabel               matlab.ui.control.Label
        YEditField                    matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        thEditField                   matlab.ui.control.NumericEditField
        thLabel                       matlab.ui.control.Label
        eEditField                    matlab.ui.control.NumericEditField
        eEditFieldLabel               matlab.ui.control.Label
        OMEditField                   matlab.ui.control.NumericEditField
        OMLabel                       matlab.ui.control.Label
        iEditField                    matlab.ui.control.NumericEditField
        iLabel                        matlab.ui.control.Label
        omEditField                   matlab.ui.control.NumericEditField
        omLabel                       matlab.ui.control.Label
        aKmEditField                  matlab.ui.control.NumericEditField
        aKmEditFieldLabel             matlab.ui.control.Label
        CreatedbyFrancescoNuzzoLabel  matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: ConversionsPanel
        function ConversionsPanelButtonDown(app, event)
            
        end

        % Value changed function: aKmEditField
        function aKmEditFieldValueChanged(app, event)
            value = app.aKmEditField.Value;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;


        end

        % Callback function
        function ConstantsButtonValueChanged(app, event)
            value = app.ConstantsButton.Value;
            if value == 1
                
                %app.ConstanstPanel.HandleVisibility =
            else
                app.ConstanstPanel.Visible = "on";
            end
            
        end

        % Callback function
        function PanelButtonDown(app, event)
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            mu = app.muEditField.Value;
            if app.KeptoCarButton.Value == 1
                %{
                if app.XEditField.Value ~= 0 || ...
                app.YEditField.Value ~= 0 || ...
                app.ZEditField.Value ~= 0 || ...
                app.VxEditField.Value ~= 0 || ...
                app.VyEditField.Value ~= 0 || ...
                app.VzEditField.Value ~= 0

                app.XEditField.Value = 0;
                app.YEditField.Value = 0;
                app.ZEditField.Value = 0;
                app.VxEditField.Value = 0;
                app.VyEditField.Value = 0;
                app.VzEditField.Value = 0;
                
                warndlg('Select Car to Kep');
                else
%}
                    a = app.aKmEditField.Value;
                    e = app.eEditField.Value;
                    i = deg2rad(app.iEditField.Value);
                    OM = deg2rad(app.OMEditField.Value);
                    om = deg2rad(app.omEditField.Value);
                    th = deg2rad(app.thEditField.Value);
                    [r,v] = kep2car(a,e,i,OM,om,th,mu);
                    app.XEditField.Value = r(1);
                    app.YEditField.Value = r(2);
                    app.ZEditField.Value = r(2);
                    app.VxEditField.Value = v(1);
                    app.VyEditField.Value = v(2);
                    app.VzEditField.Value = v(3);
                %end
                
            elseif app.CartoKepButton.Value == 1
                %{
                if app.aKmEditField.Value ~= 0 || ...
                    app.eEditField.Value ~= 0 || ...
                    deg2rad(app.iEditField.Value) ~= 0 || ...
                    deg2rad(app.OMEditField.Value) ~= 0 || ...
                    deg2rad(app.omEditField.Value) ~= 0 || ...
                    deg2rad(app.thEditField.Value) ~= 0

                    app.aKmEditField.Value = 0;
                    app.eEditField.Value = 0;
                    app.iEditField.Value = 0;
                    app.OMEditField.Value = 0;
                    app.omEditField.Value = 0;
                    app.thEditField.Value = 0;

                    warndlg('Select Kep to Car');
                else 
                %}
                r = zeros(3,1); v = zeros(3,1);
                r(1) = app.XEditField.Value;
                r(2) = app.YEditField.Value;
                r(3) = app.ZEditField.Value;
                v(1) = app.VxEditField.Value;
                v(2) = app.VyEditField.Value;
                v(3) = app.VzEditField.Value;
                [a,e,i,OM,om,th] = car2kep(r,v,mu);
                app.aKmEditField.Value = a;
                app.eEditField.Value = e;
                app.iEditField.Value = rad2deg(i);
                app.OMEditField.Value = rad2deg(OM);
                app.omEditField.Value = rad2deg(om);
                app.thEditField.Value = rad2deg(th);
                %end
            end
            value = app.PasteintoConstantsCheckBox.Value;
            if value == 1
                app.aKmEditField_2.Value = app.aKmEditField.Value;
                app.eEditField_2.Value = app.eEditField.Value;
                app.iEditField_2.Value = app.iEditField.Value;
                app.OMEditField_2.Value = app.OMEditField.Value;
                app.omEditField_2.Value = app.omEditField.Value;
                app.th_fEditField.Value = app.thEditField.Value;
            end
            
            app.EEditField.Value = 0.5*norm(v)^2-app.muEditField.Value/norm(r);
            app.pEditField.Value = app.aKmEditField.Value*(1-app.eEditField.Value^2);
            app.hEditField_2.Value = norm(cross(r,v));
            if app.eEditField.Value < 1
                app.bEditField.Value = app.aKmEditField.Value*sqrt(1-app.eEditField.Value^2);
            elseif app.eEditField.Value == 1
                app.bEditField.Value = inf;
            elseif app.eEditField.Value > 1
                app.bEditField.Value = app.aKmEditField.Value*sqrt(-1+app.eEditField.Value^2);
            end
            app.rpEditField.Value = app.pEditField.Value/(1+app.EEditField.Value);
            app.raEditField.Value = app.pEditField.Value/(1-app.EEditField.Value);
        end

        % Value changed function: PasteintoConstantsCheckBox
        function PasteintoConstantsCheckBoxValueChanged(app, event)
            
        end

        % Button pushed function: ConversionsButton
        function ConversionsButtonPushed(app, event)
                app.TabGroup.Visible = "off";
                app.ConversionsPanel.Visible = "on";
 

        end

        % Button pushed function: ConstansButton
        function ConstansButtonPushed(app, event)
                app.ConversionsPanel.Visible = "off";
                app.TabGroup.Visible = "on";
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: StateButton
        function StateButtonPushed(app, event)
            hold(app.UIAxes,"off");
            kepEl = [app.aKmEditField_2.Value,...
                app.eEditField_2.Value,...
                app.iEditField_2.Value,...
                app.OMEditField_2.Value,...
                app.omEditField_2.Value,...
                app.th_fEditField.Value];
            
         
            tetha = deg2rad(app.th_0EditField.Value):app.Delta_thEditField.Value:deg2rad(app.th_fEditField.Value);
            r = kep2car(app.aKmEditField_2.Value,...
                app.eEditField_2.Value,...
                app.iEditField_2.Value,...
                app.OMEditField_2.Value,...
                app.omEditField_2.Value,...
                tetha,...
                app.muEditField.Value);
            v = [45 30 30];
            view(app.UIAxes,v);
            plot3(app.UIAxes,r(1,end),r(2,end),r(3,end),'or','MarkerFaceColor',"#77AC30",'MarkerEdgeColor',"#77AC30",'MarkerSize',10);
            hold(app.UIAxes,"on");
            plot3(app.UIAxes,r(1,1:end),r(2,1:end),r(3,1:end),'Color',"#A2142F");
            grid(app.UIAxes,"on");
            %%
                Rt=6378;
                load('topo.mat','topo','topomap1');
                % whos topo topomap1
                contour(app.UIAxes,0:359,-89:90,topo,[0 0],'b')
                axis(app.UIAxes,'equal');
                % box on
                % set(gca,'XLim',[0 360],'YLim',[-90 90], ...
                %     'XTick',[0 60 120 180 240 300 360], ...
                %     'Ytick',[-90 -60 -30 0 30 60 90]);
                hold (app.UIAxes,"on")
                image(app.UIAxes,[0 360],[-90 90],topo,'CDataMapping', 'scaled');
                colormap(app.UIAxes,topomap1)
                [x,y,z] = sphere(app.UIAxes,100);
                % cla reset
                % axis square off
                props.AmbientStrength = 0.1;
                props.DiffuseStrength = 1;
                props.SpecularColorReflectance = .5;
                props.SpecularExponent = 20;
                props.SpecularStrength = 1;
                props.FaceColor= 'texture';
                props.EdgeColor = 'none';
                props.FaceLighting = 'phong';
                props.Cdata = topo;
                surface(app.UIAxes,x*Rt,y*Rt,z*Rt,props);
                light(app.UIAxes,'position',[1 1 1]);
                light(app.UIAxes,'position',[-1.5 0.5 -0.5], 'color', [.6 .2 .2]);
            %%


        end

        % Button pushed function: AnimationButton
        function AnimationButtonPushed(app, event)
            r = []; v = []; X = []; Y = []; Z = [];
            hold(app.UIAxes,"off");
            kepEl = [app.aKmEditField_2.Value,...
                app.eEditField_2.Value,...
                app.iEditField_2.Value,...
                app.OMEditField_2.Value,...
                app.omEditField_2.Value,...
                app.th_fEditField.Value];
            
         
            teth = deg2rad(app.th_0EditField.Value):app.Delta_thEditField.Value:(app.norbitsEditField.Value*2*pi)+deg2rad(app.th_fEditField.Value);
            
          
            [r,vel,teth] = kep2car(app.aKmEditField_2.Value,...
                app.eEditField_2.Value,...
                app.iEditField_2.Value,...
                app.OMEditField_2.Value,...
                app.omEditField_2.Value,...
                teth,...
                app.muEditField.Value);
            %%
            Rt=6378;
                load('topo.mat','topo','topomap1');
                % whos topo topomap1
                contour(app.UIAxes,0:359,-89:90,topo,[0 0],'b')
                axis(app.UIAxes,'equal');
                % box on
                % set(gca,'XLim',[0 360],'YLim',[-90 90], ...
                %     'XTick',[0 60 120 180 240 300 360], ...
                %     'Ytick',[-90 -60 -30 0 30 60 90]);
                hold (app.UIAxes,"on")
                image(app.UIAxes,[0 360],[-90 90],topo,'CDataMapping', 'scaled');
                colormap(app.UIAxes,topomap1)
                [x,y,z] = sphere(app.UIAxes,100);
                % cla reset
                % axis square off
                props.AmbientStrength = 0.1;
                props.DiffuseStrength = 1;
                props.SpecularColorReflectance = .5;
                props.SpecularExponent = 20;
                props.SpecularStrength = 1;
                props.FaceColor= 'texture';
                props.EdgeColor = 'none';
                props.FaceLighting = 'phong';
                props.Cdata = topo;
                surface(app.UIAxes,x*Rt,y*Rt,z*Rt,props);
                light(app.UIAxes,'position',[1 1 1]);
                light(app.UIAxes,'position',[-1.5 0.5 -0.5], 'color', [.6 .2 .2]);
            %%
            hold(app.UIAxes,"on");
            X = r(1,:); Y = r(2,:); Z = r(3,:);
            plot3(app.UIAxes,X,Y,Z,'Color',"none");
            grid(app.UIAxes,"on");
            
            % Define an indefinite plot
            v = [45 30 30];
            h = plot3(app.UIAxes,nan,nan,nan,'or','MarkerFaceColor',"#77AC30",'MarkerEdgeColor',"#77AC30",'MarkerSize',10);
            
            traj = plot3(app.UIAxes,nan,nan,nan,'Color',"#A2142F");
            view(app.UIAxes,v);
            % Define the step animation
            step_animation = 2;
            % Define the moving point
            
            %for j = 1 : app.norbitsEditField.Value
            i = 0;
                while i < length(X)-2
                    i = i + step_animation;
                    set(traj,'XData',X(1:i),'YData',Y(1:i),'ZData',Z(1:i));
                    hold(app.UIAxes,"on");
                    set(h,'XData',X(i),'YData',Y(i),'ZData',Z(i));
                    drawnow
                    
                    
                    app.XEditField.Value = X(i);
                    app.YEditField.Value = Y(i);
                    app.ZEditField.Value = Z(i);
                    app.VxEditField.Value = vel(1,i);
                    app.VyEditField.Value = vel(2,i);
                    app.VzEditField.Value = vel(3,i);
                    app.thEditField.Value = rad2deg(teth(i))-floor(rad2deg(teth(i))/360)*360;
                    
                    while app.Button_3.Value == 1
                        pause(0.01);
                        while app.Button_4.Value == 1
                            i = i - step_animation;
                            pause(0.01);
                            app.XEditField.Value = X(i);
                            app.YEditField.Value = Y(i);
                            app.ZEditField.Value = Z(i);
                            app.VxEditField.Value = vel(1,i);
                            app.VyEditField.Value = vel(2,i);
                            app.VzEditField.Value = vel(3,i);
                            app.thEditField.Value = rad2deg(teth(i))-floor(rad2deg(teth(i))/360)*360;
                            set(traj,'XData',X(1:i),'YData',Y(1:i),'ZData',Z(1:i));
                            hold(app.UIAxes,"on");
                            set(h,'XData',X(i),'YData',Y(i),'ZData',Z(i));
                            drawnow
                        end
                        while app.Button_5.Value == 1
                            i = i + step_animation;
                            pause(0.01);
                            app.XEditField.Value = X(i);
                            app.YEditField.Value = Y(i);
                            app.ZEditField.Value = Z(i);
                            app.VxEditField.Value = vel(1,i);
                            app.VyEditField.Value = vel(2,i);
                            app.VzEditField.Value = vel(3,i);
                            app.thEditField.Value = rad2deg(teth(i))-floor(rad2deg(teth(i))/360)*360;
                            set(traj,'XData',X(1:i),'YData',Y(1:i),'ZData',Z(1:i));
                            hold(app.UIAxes,"on");
                            set(h,'XData',X(i),'YData',Y(i),'ZData',Z(i));
                            drawnow
                        end
                            app.XEditField.Value = X(i);
                            app.YEditField.Value = Y(i);
                            app.ZEditField.Value = Z(i);
                            app.VxEditField.Value = vel(1,i);
                            app.VyEditField.Value = vel(2,i);
                            app.VzEditField.Value = vel(3,i);
                            app.thEditField.Value = rad2deg(teth(i))-floor(rad2deg(teth(i))/360)*360;
                            set(traj,'XData',X(1:i),'YData',Y(1:i),'ZData',Z(1:i));
                            hold(app.UIAxes,"on");
                            set(h,'XData',X(i),'YData',Y(i),'ZData',Z(i));
                            drawnow
                        
                    end
                    

                    pause(app.Delta_thEditField.Value/10);
                    
                end
                
                
            %end
        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
            value = app.Button_3.Value;
            
        end

        % Value changed function: Button_4
        function Button_4ValueChanged(app, event)
            value = app.Button_4.Value;
            
        end

        % Value changed function: aKmEditField_2
        function aKmEditField_2ValueChanged(app, event)
       
        end

        % Value changed function: iEditField_2
        function iEditField_2ValueChanged(app, event)
            
        end

        % Value changed function: eEditField_2
        function eEditField_2ValueChanged(app, event)
            
        end

        % Value changed function: OMEditField_2
        function OMEditField_2ValueChanged(app, event)
            
        end

        % Value changed function: muEditField
        function muEditFieldValueChanged(app, event)
            value = app.muEditField.Value;
            
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            
           
            [r,v] = kep2car(app.aKmEditField_2.Value,app.eEditField_2.Value,app.iEditField_2.Value,app.OMEditField_2.Value,app.omEditField_2.Value,app.th_fEditField.Value,app.muEditField.Value);
            app.EEditField.Value = 0.5*norm(v)^2-app.muEditField.Value/norm(r);
            app.pEditField.Value = app.aKmEditField_2.Value*(1-app.eEditField.Value^2);
            app.hEditField_2.Value = norm(cross(r,v));
            if app.eEditField_2.Value < 1
                app.bEditField.Value = app.aKmEditField_2.Value*sqrt(1-app.eEditField.Value^2);
            elseif app.eEditField_2.Value == 1
                
                app.pEditField.Value = 2*app.aKmEditField_2.Value;
                app.hEditField_2.Value = sqrt(app.muEditField.Value*app.pEditField.Value);
                app.bEditField.Value = inf;
            elseif app.eEditField_2.Value > 1
                app.bEditField.Value = app.aKmEditField_2.Value*sqrt(-1+app.eEditField_2.Value^2);
            end
            app.rpEditField.Value = app.pEditField.Value/(1+app.EEditField.Value);
            app.raEditField.Value = app.pEditField.Value/(1-app.EEditField.Value);
            %%
            app.aKmEditField.Value = app.aKmEditField_2.Value;
            app.eEditField.Value = app.eEditField_2.Value;
            app.iEditField.Value = app.iEditField_2.Value;
            app.OMEditField.Value = app.OMEditField_2.Value;
            app.omEditField.Value = app.OMEditField_2.Value;
            app.thEditField.Value = app.th_fEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.CreatedbyFrancescoNuzzoLabel = uilabel(app.UIFigure);
            app.CreatedbyFrancescoNuzzoLabel.Text = 'Created by Francesco Nuzzo';
            app.ConversionsPanel = uipanel(app.UIFigure);
            app.ConversionsPanel.Title = 'Conversions';
            app.ConversionsPanel.Visible = 'off';
            app.ConversionsPanel.ButtonDownFcn = createCallbackFcn(app, @ConversionsPanelButtonDown, true);
            app.aKmEditFieldLabel = uilabel(app.ConversionsPanel);
            app.aKmEditFieldLabel.Text = 'a [Km]';
            app.aKmEditField = uieditfield(app.ConversionsPanel, 'numeric');
            app.aKmEditField.ValueChangedFcn = createCallbackFcn(app, @aKmEditFieldValueChanged, true);
            app.omLabel = uilabel(app.ConversionsPanel);
            app.omLabel.Text = 'om [°]';
            app.omEditField = uieditfield(app.ConversionsPanel, 'numeric');
            app.iLabel = uilabel(app.ConversionsPanel);
            app.iLabel.Text = 'i [°]';
            app.iEditField = uieditfield(app.ConversionsPanel, 'numeric');
            app.OMLabel = uilabel(app.ConversionsPanel);
            app.OMLabel.Text = 'OM [°]';
            app.OMEditField = uieditfield(app.ConversionsPanel, 'numeric');
            app.eEditFieldLabel = uilabel(app.ConversionsPanel);
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.ConversionsPanel, 'numeric');
            app.thLabel = uilabel(app.ConversionsPanel);
            app.thLabel.Text = 'th [°]';
            app.thEditField = uieditfield(app.ConversionsPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.ConversionsPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.ConversionsPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.ConversionsPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.ConversionsPanel, 'numeric');
            app.VxEditFieldLabel = uilabel(app.ConversionsPanel);
            app.VxEditFieldLabel.Text = 'V x';
            app.VxEditField = uieditfield(app.ConversionsPanel, 'numeric');
            app.VyEditField_2Label = uilabel(app.ConversionsPanel);
            app.VyEditField_2Label.Text = 'V y';
            app.VyEditField = uieditfield(app.ConversionsPanel, 'numeric');
            app.VzEditFieldLabel = uilabel(app.ConversionsPanel);
            app.VzEditFieldLabel.Text = 'V z';
            app.VzEditField = uieditfield(app.ConversionsPanel, 'numeric');
            app.ButtonGroup = uibuttongroup(app.ConversionsPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.CartoKepButton = uiradiobutton(app.ButtonGroup);
            app.CartoKepButton.Text = 'Car to Kep';
            app.CartoKepButton.Value = true;
            app.KeptoCarButton = uiradiobutton(app.ButtonGroup);
            app.KeptoCarButton.Text = 'Kep to Car';
            app.RunButton = uibutton(app.ConversionsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.PasteintoConstantsCheckBox = uicheckbox(app.ConversionsPanel);
            app.PasteintoConstantsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PasteintoConstantsCheckBoxValueChanged, true);
            app.PasteintoConstantsCheckBox.Text = ' Paste into Constants';
            app.XEditFieldLabel = uilabel(app.ConversionsPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.ConversionsPanel, 'numeric');
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.ConversionsButton = uibutton(app.Panel, 'push');
            app.ConversionsButton.ButtonPushedFcn = createCallbackFcn(app, @ConversionsButtonPushed, true);
            app.ConversionsButton.Text = 'Conversions';
            app.ConstansButton = uibutton(app.Panel, 'push');
            app.ConstansButton.ButtonPushedFcn = createCallbackFcn(app, @ConstansButtonPushed, true);
            app.ConstansButton.Text = 'Constans';
            app.StateButton = uibutton(app.Panel, 'push');
            app.StateButton.ButtonPushedFcn = createCallbackFcn(app, @StateButtonPushed, true);
            app.StateButton.Text = 'State';
            app.AnimationButton = uibutton(app.Panel, 'push');
            app.AnimationButton.ButtonPushedFcn = createCallbackFcn(app, @AnimationButtonPushed, true);
            app.AnimationButton.Text = 'Animation';
            app.norbitsEditFieldLabel = uilabel(app.Panel);
            app.norbitsEditFieldLabel.Text = 'n orbits';
            app.norbitsEditField = uieditfield(app.Panel, 'numeric');
            app.norbitsEditField.Value = 1;
            app.Button_3 = uibutton(app.Panel, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Text = '||';
            app.Button_4 = uibutton(app.Panel, 'state');
            app.Button_4.ValueChangedFcn = createCallbackFcn(app, @Button_4ValueChanged, true);
            app.Button_4.Text = '<<';
            app.Button_5 = uibutton(app.Panel, 'state');
            app.Button_5.Text = '>>';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Visible = 'off';
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.muEditFieldLabel = uilabel(app.InputTab);
            app.muEditFieldLabel.Text = 'mu';
            app.muEditField = uieditfield(app.InputTab, 'numeric');
            app.muEditField.ValueChangedFcn = createCallbackFcn(app, @muEditFieldValueChanged, true);
            app.muEditField.Value = 398600.433;
            app.th_0EditFieldLabel = uilabel(app.InputTab);
            app.th_0EditFieldLabel.Text = 'th_0';
            app.th_0EditField = uieditfield(app.InputTab, 'numeric');
            app.th_fEditFieldLabel = uilabel(app.InputTab);
            app.th_fEditFieldLabel.Text = 'th_f';
            app.th_fEditField = uieditfield(app.InputTab, 'numeric');
            app.aKmEditField_2Label = uilabel(app.InputTab);
            app.aKmEditField_2Label.Text = 'a [Km]';
            app.aKmEditField_2 = uieditfield(app.InputTab, 'numeric');
            app.aKmEditField_2.ValueChangedFcn = createCallbackFcn(app, @aKmEditField_2ValueChanged, true);
            app.omEditField_2Label = uilabel(app.InputTab);
            app.omEditField_2Label.Text = 'om [°]';
            app.omEditField_2 = uieditfield(app.InputTab, 'numeric');
            app.iEditField_2Label = uilabel(app.InputTab);
            app.iEditField_2Label.Text = 'i [°]';
            app.iEditField_2 = uieditfield(app.InputTab, 'numeric');
            app.iEditField_2.ValueChangedFcn = createCallbackFcn(app, @iEditField_2ValueChanged, true);
            app.OMEditField_2Label = uilabel(app.InputTab);
            app.OMEditField_2Label.Text = 'OM [°]';
            app.OMEditField_2 = uieditfield(app.InputTab, 'numeric');
            app.OMEditField_2.ValueChangedFcn = createCallbackFcn(app, @OMEditField_2ValueChanged, true);
            app.eEditField_2Label = uilabel(app.InputTab);
            app.eEditField_2Label.Text = 'e';
            app.eEditField_2 = uieditfield(app.InputTab, 'numeric');
            app.eEditField_2.ValueChangedFcn = createCallbackFcn(app, @eEditField_2ValueChanged, true);
            app.Delta_thEditFieldLabel = uilabel(app.InputTab);
            app.Delta_thEditFieldLabel.Text = 'Delta_th';
            app.Delta_thEditField = uieditfield(app.InputTab, 'numeric');
            app.Delta_thEditField.Value = 0.0174532925199433;
            app.RefreshButton = uibutton(app.InputTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.OtherconstantsTab = uitab(app.TabGroup);
            app.OtherconstantsTab.Title = 'Other constants';
            app.GridLayout = uigridlayout(app.OtherconstantsTab);
            app.EEditFieldLabel = uilabel(app.GridLayout);
            app.EEditFieldLabel.Text = 'E';
            app.EEditField = uieditfield(app.GridLayout, 'numeric');
            app.EEditField.Editable = 'off';
            app.pEditFieldLabel = uilabel(app.GridLayout);
            app.pEditFieldLabel.Text = 'p';
            app.pEditField = uieditfield(app.GridLayout, 'numeric');
            app.pEditField.Editable = 'off';
            app.hLabel = uilabel(app.GridLayout);
            app.hLabel.Text = '|h|';
            app.hEditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.hEditField_2.Editable = 'off';
            app.bEditFieldLabel = uilabel(app.GridLayout);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.GridLayout, 'numeric');
            app.bEditField.Editable = 'off';
            app.rpEditFieldLabel = uilabel(app.GridLayout);
            app.rpEditFieldLabel.Text = 'rp';
            app.rpEditField = uieditfield(app.GridLayout, 'numeric');
            app.rpEditField.Editable = 'off';
            app.raEditFieldLabel = uilabel(app.GridLayout);
            app.raEditFieldLabel.Text = 'ra';
            app.raEditField = uieditfield(app.GridLayout, 'numeric');
            app.raEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end